classdef ImageLoading < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        LoadImageButton           matlab.ui.control.Button
        Image                     matlab.ui.control.Image
        NextStepButton            matlab.ui.control.Button
        Step2LoadyourImagesLabel  matlab.ui.control.Label
        LocationLabel             matlab.ui.control.Label
        locLabel                  matlab.ui.control.Label
        PixelSizeLabel            matlab.ui.control.Label
        pixsizLabel               matlab.ui.control.Label
        MethodLabel               matlab.ui.control.Label
        metLabel                  matlab.ui.control.Label
        ProjectMetadataLabel      matlab.ui.control.Label
        UITable                   matlab.ui.control.Table
        RemoveImageButton         matlab.ui.control.Button
        PictureMetadataLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        selectedRow;
        location;
        pixelSize;
        method;
    end
    methods (Access = private)
        
        function updateimage(app,imagefile)
            app.Image.ImageSource = imagefile;
        end
        
        function loadimage(app)                 
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [file, path] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(path))
               filename = [path file];
            else
                return;
            end
            
            image = imread(filename);
            [height, width, dim] = size(image);
             app.UITable.Data = [app.UITable.Data ; [filename,"1990",strcat(num2str(height),"x", num2str(width), "x",num2str(dim)),""]];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, location, pixelSize, method)
            app.location = location;
            app.pixelSize = pixelSize;
            app.method = method;
          
            app.locLabel.Text = location;
            app.pixsizLabel.Text = pixelSize;
            app.metLabel.Text = method;
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            loadimage(app);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.selectedRow = indices(1);
            updateimage(app,app.UITable.Data(indices(1)));
        end

        % Button pushed function: RemoveImageButton
        function RemoveImageButtonPushed(app, event)
            if(isempty(app.UITable.Data))
                return;
            end
            app.UITable.Data(app.selectedRow,:) = []; % delete the row
            app.selectedRow = app.selectedRow-1; % subtract the selected row reference
            if(app.selectedRow >= 1)
                updateimage(app,app.UITable.Data(app.selectedRow));
            else
                updateimage(app,"");
            end
        end

        % Button pushed function: NextStepButton
        function NextStepButtonPushed(app, event)
            if(isempty(app.UITable.Data))
                return;
            end
            % for i = 1:size(app.UITable.Data)
                   % disp(app.UITable.Data(i,1));
                   % disp(app.UITable.Data(i,2));
                   % disp(app.UITable.Data(i,3));
            % end
            % disp(app.UITable.Data(1,3));
            % disp(app.UITable.Data(1:end,1:end));
            % disp(app.UITable.Data(1:end,1));
            MaskGeneration(app.locLabel.Text,app.pixsizLabel.Text,app.metLabel.Text,app.UITable.Data)
            app.delete;
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            loadimage(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Enable = 'off';
            app.FileMenu.Text = 'File';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.Tooltip = {'Here you can load as many images as you want and edit their image year in the table blow.'; ''; 'Warning: all images should have the same size(x;y;dimensions) and be taken by the same satellite at different dates for meaningful results.'};
            app.NextStepButton = uibutton(app.UIFigure, 'push');
            app.NextStepButton.ButtonPushedFcn = createCallbackFcn(app, @NextStepButtonPushed, true);
            app.NextStepButton.Text = 'Next Step';
            app.Step2LoadyourImagesLabel = uilabel(app.UIFigure);
            app.Step2LoadyourImagesLabel.Text = 'Step 2: Load your Images';
            app.LocationLabel = uilabel(app.UIFigure);
            app.LocationLabel.Text = 'Location:';
            app.locLabel = uilabel(app.UIFigure);
            app.locLabel.Text = 'loc';
            app.PixelSizeLabel = uilabel(app.UIFigure);
            app.PixelSizeLabel.Text = 'Pixel Size:';
            app.pixsizLabel = uilabel(app.UIFigure);
            app.pixsizLabel.Text = 'pix siz';
            app.MethodLabel = uilabel(app.UIFigure);
            app.MethodLabel.Text = 'Method:';
            app.metLabel = uilabel(app.UIFigure);
            app.metLabel.Text = 'met';
            app.ProjectMetadataLabel = uilabel(app.UIFigure);
            app.ProjectMetadataLabel.Text = 'Project Metadata';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Image Filepath'; 'Image Year'; 'Size(x;y;dimensions)'; 'Masked Image Filepath'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true true true];
            app.UITable.ColumnEditable = [false true false false];
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.RemoveImageButton = uibutton(app.UIFigure, 'push');
            app.RemoveImageButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveImageButtonPushed, true);
            app.RemoveImageButton.Text = 'Remove Image';
            app.PictureMetadataLabel = uilabel(app.UIFigure);
            app.PictureMetadataLabel.Text = 'Picture Metadata';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageLoading(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end