classdef MaskGeneration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        Panel_3                  matlab.ui.container.Panel
        RegionGrowingLabel       matlab.ui.control.Label
        GrowNewRegionButton      matlab.ui.control.Button
        Panel_2                  matlab.ui.container.Panel
        OtsusThresholdLabel      matlab.ui.control.Label
        Slider_2                 matlab.ui.control.Slider
        Panel                    matlab.ui.container.Panel
        ManuelThresholdLabel     matlab.ui.control.Label
        Slider                   matlab.ui.control.Slider
        FlipMaskButton           matlab.ui.control.Button
        Step3GenerateMasksLabel  matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        ImageCounter             matlab.ui.control.Label
        Year0000Label            matlab.ui.control.Label
        FilenamexxxLabel         matlab.ui.control.Label
        NextButton               matlab.ui.control.Button
        PrevButton               matlab.ui.control.Button
        ResetMaskButton          matlab.ui.control.Button
        NextStepButton           matlab.ui.control.Button
        DeforestedArea0Label     matlab.ui.control.Label
        UITable                  matlab.ui.control.Table
        SaveMaskButton           matlab.ui.control.Button
        Image2                   matlab.ui.control.Image
        Image3                   matlab.ui.control.Image
    end

    
    properties (Access = private)
        location; %char
        pixelSize; %char
        method; %char 
        imageData; %string
        displayedImageIndex = 1; %double
        originalImage; %uint8
        maskedImage; %logical
        combinedImage; %uint8
        flippedMask = 0; %double
        masks; %cell
        
    end
    
    methods (Access = private)
        
        function updateDisplayedImages(app)

            app.originalImage = imread(app.imageData(app.displayedImageIndex,1));

            
            % PREPARE A NEW MASKING START POINT OR LOAD AN EXISTING MASKING START POINT
            if(isempty(app.masks{app.displayedImageIndex})) % Generate a brand new mask starting point
                    app.maskedImage = rgb2gray(app.originalImage);
                
                if app.method == "Region Growing"
                    app.maskedImage(:) = 0; % we want to start with a blacked out image of the original
                end
                app.masks{app.displayedImageIndex} = app.maskedImage;
            else
                app.maskedImage = app.masks{app.displayedImageIndex}; % Load a mask if it exists
            end

            % Do manual thresholding
            if app.method == "Manual Thresholding"
                if app.flippedMask == 0
                    app.maskedImage = rgb2gray(app.originalImage) >= app.Slider.Value;
                else
                    app.maskedImage = rgb2gray(app.originalImage) <= app.Slider.Value;
                end
               app.masks{app.displayedImageIndex} = app.maskedImage; % we save the mask

            end
            
            app.ImageCounter.Text = strcat("Image ", num2str(app.displayedImageIndex), " of ", num2str(size(app.imageData,1)));
            app.Image.ImageSource = app.originalImage;
            app.Image2.ImageSource = im2uint8(cat(3,app.maskedImage,app.maskedImage,app.maskedImage));
            app.combinedImage = im2uint8(cat(3,app.maskedImage,app.maskedImage,app.maskedImage)) + app.originalImage;
            app.Image3.ImageSource = app.combinedImage;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, location, pixelSize, method, imageData)
            app.location = location;
            app.pixelSize = pixelSize;
            app.method = method;
            app.imageData = imageData;
            app.UITable.Data = imageData;
            if (strcmp(method,"Manual Thresholding"))
                app.Panel.Visible = true;
            elseif (strcmp(method,"Otsu's Thresholding"))
                app.Panel_2.Visible = true;
            elseif (strcmp(method,"Region Growing"))
                app.Panel_3.Visible = true;
            end
            app.masks = cell(1,size(imageData,1));
            updateDisplayedImages(app);
            
        end

        % Button pushed function: SaveMaskButton
        function SaveMaskButtonPushed(app, event)
            selectedDirectory = uigetdir();
             % Make sure user didn't cancel uigetdir dialog
            if (selectedDirectory == 0)
                return;
            end
            imwrite(app.maskedImage,strcat(selectedDirectory,"\img",num2str(app.displayedImageIndex),"_mask.png"));
            imwrite(cat(3,app.maskedImage,app.maskedImage,app.maskedImage) + app.originalImage,strcat(selectedDirectory,"\img",num2str(app.displayedImageIndex),"_overlayed_mask.png"));

            app.UITable.Data(app.displayedImageIndex,4) = strcat(selectedDirectory,"\img",num2str(app.displayedImageIndex),"_mask.png");
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.displayedImageIndex < size(app.imageData,1)
                app.displayedImageIndex = app.displayedImageIndex + 1;
                updateDisplayedImages(app);
            end

        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            if app.displayedImageIndex > 1
                app.displayedImageIndex = app.displayedImageIndex - 1;
                updateDisplayedImages(app);
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
           updateDisplayedImages(app);
        end

        % Button pushed function: FlipMaskButton
        function FlipMaskButtonPushed(app, event)
            if app.flippedMask == 0
                app.flippedMask = 1;
            else
                app.flippedMask = 0;
            end
            updateDisplayedImages(app);

        end

        % Button pushed function: GrowNewRegionButton
        function GrowNewRegionButtonPushed(app, event)
            % Do region growing


            try ginput(1)
            catch 
            end
            
           app.maskedImage = imnoise(app.maskedImage,'salt & pepper',0.1); %TODO
           app.masks{app.displayedImageIndex} = app.maskedImage; % we save so we can keep adding information to it
            
            app.updateDisplayedImages();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Enable = 'off';
            app.FileMenu.Text = 'File';
            app.Panel_3 = uipanel(app.UIFigure);
            app.RegionGrowingLabel = uilabel(app.Panel_3);
            app.RegionGrowingLabel.Text = 'Region Growing';
            app.GrowNewRegionButton = uibutton(app.Panel_3, 'push');
            app.GrowNewRegionButton.ButtonPushedFcn = createCallbackFcn(app, @GrowNewRegionButtonPushed, true);
            app.GrowNewRegionButton.Text = 'Grow New Region';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Visible = 'off';
            app.OtsusThresholdLabel = uilabel(app.Panel_2);
            app.OtsusThresholdLabel.Text = 'Otsu''s Threshold';
            app.Slider_2 = uislider(app.Panel_2);
            app.Slider_2.Enable = 'off';
            app.Slider_2.Value = 50;
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Visible = 'off';
            app.ManuelThresholdLabel = uilabel(app.Panel);
            app.ManuelThresholdLabel.Text = 'Manuel Threshold';
            app.Slider = uislider(app.Panel);
            app.Slider.Limits = [0 255];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 10;
            app.FlipMaskButton = uibutton(app.Panel, 'push');
            app.FlipMaskButton.ButtonPushedFcn = createCallbackFcn(app, @FlipMaskButtonPushed, true);
            app.FlipMaskButton.Text = 'Flip Mask';
            app.Step3GenerateMasksLabel = uilabel(app.UIFigure);
            app.Step3GenerateMasksLabel.Text = 'Step 3: Generate Masks';
            app.Image = uiimage(app.UIFigure);
            app.ImageCounter = uilabel(app.UIFigure);
            app.ImageCounter.Text = 'Image 0 of 0';
            app.Year0000Label = uilabel(app.UIFigure);
            app.Year0000Label.Enable = 'off';
            app.Year0000Label.Text = 'Year: 0000';
            app.FilenamexxxLabel = uilabel(app.UIFigure);
            app.FilenamexxxLabel.Enable = 'off';
            app.FilenamexxxLabel.Text = 'Filename: xxx';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next ->';
            app.PrevButton = uibutton(app.UIFigure, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Text = '<- Prev';
            app.ResetMaskButton = uibutton(app.UIFigure, 'push');
            app.ResetMaskButton.Enable = 'off';
            app.ResetMaskButton.Text = 'Reset Mask';
            app.NextStepButton = uibutton(app.UIFigure, 'push');
            app.NextStepButton.Enable = 'off';
            app.NextStepButton.Text = 'Next Step';
            app.DeforestedArea0Label = uilabel(app.UIFigure);
            app.DeforestedArea0Label.Enable = 'off';
            app.DeforestedArea0Label.Text = 'Deforested Area: 0';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Image Filepath'; 'Image Year'; 'Size(x;y;dimensions)'; 'Masked Image Filepath'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true true true];
            app.UITable.ColumnEditable = [false true false false];
            app.SaveMaskButton = uibutton(app.UIFigure, 'push');
            app.SaveMaskButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMaskButtonPushed, true);
            app.SaveMaskButton.Text = 'Save Mask';
            app.Image2 = uiimage(app.UIFigure);
            app.Image3 = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MaskGeneration(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end